classdef Testumgebung_MatlabSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TestumgebungfrADTFFilterLabel   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        DateiladencsvButton             matlab.ui.control.Button
        AnzahlInputsEditFieldLabel      matlab.ui.control.Label
        AnzahlInputsEditField           matlab.ui.control.NumericEditField
        oderLabel_2                     matlab.ui.control.Label
        SprungfunktionButton            matlab.ui.control.Button
        SinusButton                     matlab.ui.control.Button
        OutputTab                       matlab.ui.container.Tab
        AnzahlOutputsEditFieldLabel     matlab.ui.control.Label
        AnzahlOutputsEditField          matlab.ui.control.NumericEditField
        CCodeTab                        matlab.ui.container.Tab
        CCodeladenButton                matlab.ui.control.Button
        ADTF2SimulinkparsenCheckBox     matlab.ui.control.CheckBox
        oderLabel                       matlab.ui.control.Label
        CCodehiereingebenButton         matlab.ui.control.Button
        AusgabeTab                      matlab.ui.container.Tab
        PlotEinschwingverhaltenCheckBox  matlab.ui.control.CheckBox
        BodeplotnotactiveyetCheckBox    matlab.ui.control.CheckBox
        SimulierenButton                matlab.ui.control.Button
        ReporterstellenButton           matlab.ui.control.Button
        SimulationsZeitEditFieldLabel   matlab.ui.control.Label
        SimulationsZeitEditField        matlab.ui.control.NumericEditField
        ReglerparameternotactiveyetTab  matlab.ui.container.Tab
        ParameterladencsvButton_2       matlab.ui.control.Button
        odermanuelleParametereingabeLabel_2  matlab.ui.control.Label
        KpEditField_2Label              matlab.ui.control.Label
        KpEditField_2                   matlab.ui.control.NumericEditField
        KiEditField_2Label              matlab.ui.control.Label
        KiEditField_2                   matlab.ui.control.NumericEditField
        KdEditField_2Label              matlab.ui.control.Label
        KdEditField_2                   matlab.ui.control.NumericEditField
        ParameterimHeaderfileanpassenButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: ParameterladencsvButton_2
        function ParameterladencsvButton_2Pushed(app, event)
            global bParameterLoad;
            global K_p
            global K_i
            global K_d
            bParameterLoad = 1;
            text = 'Auswahl Reglerparameter';
            [filename, path] = uigetfile('*.csv',text);
            file = [path, filename];
            params = import_csv_file(file);
            K_p = cell2mat(params(1,:));
            K_i = cell2mat(params(2,:));
            K_d = cell2mat(params(3,:));
        end

        % Button pushed function: DateiladencsvButton
        function DateiladencsvButtonPushed(app, event)
            global data
            text = 'Auswahl Inputparameter';
            [filename, path] = uigetfile('*.csv',text);
            file = [path, filename];
            input = import_csv_file(file,app.AnzahlInputsEditField.Value);
            
            for t = 1:app.AnzahlInputsEditField.Value
                data(:,t) = table2array(input(:,t));
            end
            %size_data = size(data,1);
            
        end

        % Button pushed function: SimulierenButton
        function SimulierenButtonPushed(app, event)
            % ------ das ist ein Teil der Logik um Headerfiles zu manipulieren
            global bParameterLoad
            global K_p
            global K_i
            global K_d
            global data
            
            if bParameterLoad == 0 % no parameters loaded
                Kp = app.KpEditField_2.Value;
                Ki = app.KiEditField_2.Value;
                Kd = app.KdEditField_2.Value;
            else % parameters of file
                Kp = K_p;
                Ki = K_i;
                Kd = K_d;  
            end
            %----------------------------
            % Simulate
            rowTimes = seconds(0:0.2:app.SimulationsZeitEditField.Value)';
            
            time_array = zeros(size(rowTimes,1),1);
            change_size = floor(size(rowTimes,1)/size(data,1));
            
            for k = 1:app.AnzahlInputsEditField.Value
                % create array of inputdata in dependence to SimTime
                param = 1;
                for t = 1:size(time_array,1)
                    if (t > 1) && (mod(t,change_size) == 0) % Bedingung, um data im time_array in gleichmäßigen Zeitabständen zu befüllen
                        param = param + 1;
                        if param > size(data,1)
                            param = size(data,1);
                        end
                    end 
                    time_array(t) = data(param,k);
                end
                TT{k} = array2timetable(time_array,'RowTimes', rowTimes); 
            end
            % create timetable for the Inputs
            
            %sim_data = TT;
             
            
            % write data in simulink model workspace
            system_name = 'DasBlaueAuto_Controller_Model';
            open_system(system_name);
            mdlWks = get_param(system_name, 'ModelWorkspace');
            for k = 1:app.AnzahlInputsEditField.Value
                assignin(mdlWks, ['sim_data', num2str(k)], TT{k});
                set_param([system_name, '/From Workspace', num2str(k)], 'VariableName', ['sim_data', num2str(k)]);
            end
           
            save_system(system_name);
            
            simOutput = sim('DasBlaueAuto_Controller_Model','ReturnWorkspaceOutputs','on', 'LoggingToFile', 'on', 'TimeOut', app.SimulationsZeitEditField.Value);
            %sim('DasBlaueAuto_Controller_Model');
            
            if app.PlotEinschwingverhaltenCheckBox.Value
                % plot inputs
                hold(app.UIAxes, 'on');
                c={'Input1','Input2','Input3','Input4','Input5'};
                for k = 1:app.AnzahlInputsEditField.Value
                   plot(app.UIAxes, TT{k}.Time, TT{k}.time_array); 
                   
                end
                
                
                % plot outputs (in .mat-file)
                output_data = load('simout1.mat');
                plot(app.UIAxes, seconds(output_data.ans.Time), output_data.ans.Data); 
                legend(app.UIAxes,c(1:k), 'Output');
                
                xlabel(app.UIAxes,'Time');
                ylabel(app.UIAxes,'Value');
                
                % text(app.UIAxes, x(1), y(1), '\leftarrow 1');
                %app.UIAxes.
                
                
            end
        end

        % Button pushed function: SprungfunktionButton
        function SprungfunktionButtonPushed(app, event)
            app.SinusButton.BackgroundColor = 'w';
            app.SprungfunktionButton.BackgroundColor = 'g';
        end

        % Button pushed function: SinusButton
        function SinusButtonPushed(app, event)
            app.SinusButton.BackgroundColor = 'g';
            app.SprungfunktionButton.BackgroundColor = 'w';
        end

        % Button pushed function: CCodeladenButton
        function CCodeladenButtonPushed(app, event)
            % load C++ Code
            text = 'Auswahl Sourcefile';
            [sourcefile, sourcepath] = uigetfile('*.cpp',text);
            
            
            text2 = 'Auswahl Headerfile';
            [headerfile, headerpath] = uigetfile('*.h',text2);
           
            % create Simulink model
            cpp2mat(sourcepath, sourcefile, headerpath, headerfile);
            
            % add Input/Output ports to Simulink model
            configureSimulinkModel(app.AnzahlInputsEditField.Value, app.AnzahlOutputsEditField.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TestumgebungfrADTFFilterLabel = uilabel(app.UIFigure);
            app.TestumgebungfrADTFFilterLabel.Text = 'Testumgebung für ADTF-Filter';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.DateiladencsvButton = uibutton(app.InputTab, 'push');
            app.DateiladencsvButton.ButtonPushedFcn = createCallbackFcn(app, @DateiladencsvButtonPushed, true);
            app.DateiladencsvButton.Text = 'Datei laden (.csv)';
            app.AnzahlInputsEditFieldLabel = uilabel(app.InputTab);
            app.AnzahlInputsEditFieldLabel.Text = 'Anzahl Inputs';
            app.AnzahlInputsEditField = uieditfield(app.InputTab, 'numeric');
            app.AnzahlInputsEditField.Limits = [0 Inf];
            app.AnzahlInputsEditField.Value = 1;
            app.oderLabel_2 = uilabel(app.InputTab);
            app.oderLabel_2.Text = 'oder';
            app.SprungfunktionButton = uibutton(app.InputTab, 'push');
            app.SprungfunktionButton.ButtonPushedFcn = createCallbackFcn(app, @SprungfunktionButtonPushed, true);
            app.SprungfunktionButton.Text = 'Sprungfunktion';
            app.SinusButton = uibutton(app.InputTab, 'push');
            app.SinusButton.ButtonPushedFcn = createCallbackFcn(app, @SinusButtonPushed, true);
            app.SinusButton.Text = 'Sinus';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.AnzahlOutputsEditFieldLabel = uilabel(app.OutputTab);
            app.AnzahlOutputsEditFieldLabel.Text = 'Anzahl Outputs';
            app.AnzahlOutputsEditField = uieditfield(app.OutputTab, 'numeric');
            app.AnzahlOutputsEditField.Limits = [0 Inf];
            app.AnzahlOutputsEditField.Value = 1;
            app.CCodeTab = uitab(app.TabGroup);
            app.CCodeTab.Title = 'C++ Code';
            app.CCodeladenButton = uibutton(app.CCodeTab, 'push');
            app.CCodeladenButton.ButtonPushedFcn = createCallbackFcn(app, @CCodeladenButtonPushed, true);
            app.CCodeladenButton.Text = 'C++ Code laden';
            app.ADTF2SimulinkparsenCheckBox = uicheckbox(app.CCodeTab);
            app.ADTF2SimulinkparsenCheckBox.Text = 'ADTF2Simulink parsen';
            app.oderLabel = uilabel(app.CCodeTab);
            app.oderLabel.Text = 'oder';
            app.CCodehiereingebenButton = uibutton(app.CCodeTab, 'push');
            app.CCodehiereingebenButton.Text = 'C++ Code hier eingeben';
            app.AusgabeTab = uitab(app.TabGroup);
            app.AusgabeTab.Title = 'Ausgabe';
            app.PlotEinschwingverhaltenCheckBox = uicheckbox(app.AusgabeTab);
            app.PlotEinschwingverhaltenCheckBox.Text = 'Plot Einschwingverhalten';
            app.BodeplotnotactiveyetCheckBox = uicheckbox(app.AusgabeTab);
            app.BodeplotnotactiveyetCheckBox.Text = 'Bodeplot (not active yet)';
            app.SimulierenButton = uibutton(app.AusgabeTab, 'push');
            app.SimulierenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulierenButtonPushed, true);
            app.SimulierenButton.Text = 'Simulieren';
            app.ReporterstellenButton = uibutton(app.AusgabeTab, 'push');
            app.ReporterstellenButton.Text = 'Report erstellen';
            app.SimulationsZeitEditFieldLabel = uilabel(app.AusgabeTab);
            app.SimulationsZeitEditFieldLabel.Text = 'SimulationsZeit';
            app.SimulationsZeitEditField = uieditfield(app.AusgabeTab, 'numeric');
            app.SimulationsZeitEditField.Limits = [0 Inf];
            app.SimulationsZeitEditField.Value = 10;
            app.ReglerparameternotactiveyetTab = uitab(app.TabGroup);
            app.ReglerparameternotactiveyetTab.Title = 'Reglerparameter (not active yet)';
            app.ParameterladencsvButton_2 = uibutton(app.ReglerparameternotactiveyetTab, 'push');
            app.ParameterladencsvButton_2.ButtonPushedFcn = createCallbackFcn(app, @ParameterladencsvButton_2Pushed, true);
            app.ParameterladencsvButton_2.Text = 'Parameter laden (.csv)';
            app.odermanuelleParametereingabeLabel_2 = uilabel(app.ReglerparameternotactiveyetTab);
            app.odermanuelleParametereingabeLabel_2.Text = 'oder manuelle Parametereingabe:';
            app.KpEditField_2Label = uilabel(app.ReglerparameternotactiveyetTab);
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField_2 = uieditfield(app.ReglerparameternotactiveyetTab, 'numeric');
            app.KiEditField_2Label = uilabel(app.ReglerparameternotactiveyetTab);
            app.KiEditField_2Label.Text = 'Ki';
            app.KiEditField_2 = uieditfield(app.ReglerparameternotactiveyetTab, 'numeric');
            app.KdEditField_2Label = uilabel(app.ReglerparameternotactiveyetTab);
            app.KdEditField_2Label.Text = 'Kd';
            app.KdEditField_2 = uieditfield(app.ReglerparameternotactiveyetTab, 'numeric');
            app.ParameterimHeaderfileanpassenButton = uibutton(app.ReglerparameternotactiveyetTab, 'push');
            app.ParameterimHeaderfileanpassenButton.Text = 'Parameter im Headerfile anpassen';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Filteroutput')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Testumgebung_MatlabSimulink

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end